classdef Neuron_Classifier2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LeftGrid                       matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        NeuritethicknessmEditFieldLabel  matlab.ui.control.Label
        NeuritethicknessmEditField     matlab.ui.control.NumericEditField
        maximalgapsizemEditField       matlab.ui.control.EditField
        maximalgapsizemEditFieldLabel  matlab.ui.control.Label
        NeuronsegmenterLabel           matlab.ui.control.Label
        PreprocessingLabel             matlab.ui.control.Label
        LoadrawimagesButton            matlab.ui.control.Button
        SegmentationGrid               matlab.ui.container.GridLayout
        GapbridgingCheckBox            matlab.ui.control.CheckBox
        SegmentneuronsButton           matlab.ui.control.Button
        PreprocessingGrid              matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        SEsizeEditField                matlab.ui.control.NumericEditField
        SEsizeEditFieldLabel           matlab.ui.control.Label
        NoiseremovalusingstructuralelementButton  matlab.ui.control.Button
        SegmentationLabel              matlab.ui.control.Label
        Image_Segmented                matlab.ui.control.UIAxes
        Image_Input                    matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        ClassificationGrid             matlab.ui.container.GridLayout
        ClassificationButton           matlab.ui.control.Button
        NeuronclassifierLabel          matlab.ui.control.Label
        LoadSegmentedGrid              matlab.ui.container.GridLayout
        LoadsegmentedimagesButton      matlab.ui.control.Button
        pixelsizepxmEditFieldLabel     matlab.ui.control.Label
        pixelsizepxmEditField          matlab.ui.control.NumericEditField
        FindBranchesGrid               matlab.ui.container.GridLayout
        FindsomaneuritesandbranchesButton  matlab.ui.control.Button
        minimalbranchlengthmEditField  matlab.ui.control.NumericEditField
        minimalbranchlengthmEditFieldLabel  matlab.ui.control.Label
        SomaAxonGrid                   matlab.ui.container.GridLayout
        Image_soma                     matlab.ui.control.UIAxes
        Image_axon                     matlab.ui.control.UIAxes
        Image_final_classified         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        Raw_Images % cell for input images
        TifFiles % names of input images
        pix_size = 0.2261 % better: define pixel size during load

        SEelement = 'Circle' % Structural element for noise removal
        SE_size_preMorpho = 2 % Structural element size
        smoothed_by_Morpho_images % cell for preprocessed images

        thickness_micron = 1 % neurite thickness input
        Segmented_Images % cell for segmented images

        MIN_LEN_micron = 10 % Minimal branch length
        MIN_LEN % Minmal branch length pixel
        MainFolder = pwd;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadrawimagesButton
        function load_raw_images(app, event)
            % Change button name to "Processing"
            app.LoadrawimagesButton.Text = 'Processing...';
            % Put text on top of icon
            app.LoadrawimagesButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.LoadrawimagesButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.LoadrawimagesButton.Icon = wbar;


            %% Loading a Set of Files (Batch Processing mode)
            % MainFolder = pwd;
            [~, pNameSeg] = uigetfile('*.tif');
            CurrentFolder = pNameSeg;
            cd(CurrentFolder);
            app.TifFiles = dir('*.tif');
            numfiles = length(app.TifFiles);
            mydata = cell(1, numfiles);
            for k = 1:numfiles
                mydata{k} = imread(app.TifFiles(k).name);
            end
            % save('TifFiles.mat','TifFiles');

            disp('**********************************');
            disp('Image(s) has(have) been uploaded!');
            disp('**********************************');

            % Image = mydata{k};


            %% Converting RGB and/or Multilayer .tif files to single layer binary .tif files
            for k = 1:numfiles

                % Loop through something and update waitbar
                % Update image data (royalblue)
                currentProg = min(round((size(wbar,2)-2)*(k/numfiles)),size(wbar,2)-2);
                app.LoadrawimagesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.LoadrawimagesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.LoadrawimagesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.001);

                infoSeg = imfinfo(app.TifFiles(k).name);
                num_layers = numel(infoSeg);
                BW = zeros(infoSeg(1).Height, infoSeg(1).Width);
                if num_layers == 1                % If we have a single binary image
                    BW = mydata{k};
                    if size(BW,3) == 3          % If our raw binary image is an rgb
                        BW = rgb2gray(BW);
                    end
                else                              % If the raw binary image contains several layers
                    for L = 1:num_layers
                        BW = BW | imread(app.TifFiles(k).name, L, 'Info', infoSeg);
                    end
                end
                %     if infMode
                %         figure; imshow(BW);   title('Raw Image');
                %     end
                %
                %     size(BW)
                app.Raw_Images{k} = BW;
                imshow(app.Raw_Images{k}, 'Parent', app.Image_Input);   % Showing the raw image(s)


            end

            % size(Raw_Images)
            % save('Raw_Images.mat' , 'Raw_Images');

            % msgbox(['Number of input raw image(s) = ', num2str(numfiles), ' (only the last image is shown in the GUI)! You need to pre-process and then segment your raw data before starting the classification phase!'],'Help' , 'help');

            cd(app.MainFolder);

            % remove waitbar
            app.LoadrawimagesButton.Icon = '';
            % Change button name
            app.LoadrawimagesButton.Text = 'Load raw images';

        end

        % Value changed function: SEsizeEditField
        function SEsizeEditFieldValueChanged(app, event)
            app.SE_size_preMorpho = app.SEsizeEditField.Value;
        end

        % Value changed function: NeuritethicknessmEditField
        function NeuritethicknessmEditFieldValueChanged(app, event)
            global pix_size

            % should not happen here
            if isempty(pix_size)||isnan(pix_size)
                disp('Parameter "Pixel Size" will be set to its default value!');
                pix_size = 0.2261;

            end

            app.thickness_micron = app.NeuritethicknessmEditField.Value;

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

            app.SEelement = app.DropDown.Value;
            disp(app.SEelement);
        end

        % Button pushed function: 
        % NoiseremovalusingstructuralelementButton
        function NoiseremovalusingstructuralelementButtonPushed(app, event)

            % Change button name
            app.NoiseremovalusingstructuralelementButton.Text = 'preprocessing...';
            % Put text on top of icon
            app.NoiseremovalusingstructuralelementButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.NoiseremovalusingstructuralelementButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.NoiseremovalusingstructuralelementButton.Icon = wbar;


            % size(app.Raw_Images);
            app.SE_size_preMorpho = app.SEsizeEditField.Value;
            % figure(1111); imshow(ROI_selected_images{1});

            for k = 1 : length(app.Raw_Images)
                % Loop through something and update waitbar
                % Update image data (royalblue)
                currentProg = min(round((size(wbar,2)-2)*(k/length(app.Raw_Images))),size(wbar,2)-2);
                app.NoiseremovalusingstructuralelementButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.NoiseremovalusingstructuralelementButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.NoiseremovalusingstructuralelementButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);


                Image = app.Raw_Images{k};
                [row,col,dim]=size(Image);
                filt_im_ope=zeros(row,col,dim);     % final filtered data using opening morphology
                conn=8;
                if isempty(app.SE_size_preMorpho)||isnan(app.SE_size_preMorpho)
                    disp('Since you did not input the SE size for Morphology preprocessing, it will be set to its default value');
                    app.SE_size_preMorpho = 3;     % when you do not input the SE size for preprocessing, it will be set to 3


                end


                SE_type=app.SEelement;
                % disp(SE_type);

                switch  SE_type
                    case 'Disk'              % Structural Element  = Disk
                        R=app.SE_size_preMorpho;
                        % h=waitbar(0,'Image is being filtered using disk-shaped Stractural Element (SE)! Please wait...');
                        se = strel('disk',R);

                    case 'Circle'      % Structural Element  = Square
                        R=app.SE_size_preMorpho;
                        % h=waitbar(0,'Image is being filtered using circle-shaped Stractural Element (SE)! Please wait...');
                        se = strel('square',R);

                end

                for b=1:dim       % b = Index of each band of data
                    mask=Image(:,:,b);    % Each band of data as the input
                    marker_ope = imopen(mask, se);
                    filt_im_ope(:,:,b) = imreconstruct(marker_ope,mask,conn);   % filtered band "b" , using Radius size "R"
                end

                %Smoothed_Image = filt_im_ope;

                app.smoothed_by_Morpho_images{k} = filt_im_ope;
                imshow(filt_im_ope, [], 'Parent', app.Image_Input);
                
            end
            % remove waitbar
            app.NoiseremovalusingstructuralelementButton.Icon = '';
            % Change button name
            app.NoiseremovalusingstructuralelementButton.Text = 'Noise removal using structural element';


            clear app.Raw_Images
            
            disp('Preprocessing finished!')
            % msgbox('Image(s) is(are) smoothed! You can go to the next step (i.e. Segmentation)!','Help','help');

        end

        % Button pushed function: SegmentneuronsButton
        function SegmentneuronsButtonPushed(app, event)
            % Change button name
            app.SegmentneuronsButton.Text = 'Segmenting neurons...';
            % Put text on top of icon
            app.SegmentneuronsButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.SegmentneuronsButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.SegmentneuronsButton.Icon = wbar;

            app.Segmented_Images = cell(1,length(app.smoothed_by_Morpho_images));

            %segMode = get(handles.adaptSeg,'Value'); % returns toggle state of adaptSeg : '1' ==> Adaptive , '0' ==> Non-adaptive

            if isempty(app.thickness_micron)||isnan(app.thickness_micron)
                disp('Parameter "thickness"  will be set to its default value!');
                app.thickness_micron = 1;
                %set(handles.Par_thickness,'String',num2str(app.thickness_micron));
            end

            thickness_pixel = round(app.thickness_micron / app.pix_size);

            disp(['Approximate thickness of neurites is set to ' , num2str(app.thickness_micron), ' Micron or ~', num2str(thickness_pixel), ' pixels']);

            %MainFolder = pwd;
            mkdir Segmented
            cd Segmented
            fileID = fopen('SegmentTime.txt' , 'w');
            fprintf(fileID , '%20s  %20s  %20s \r\n' , 'NeuronIndex' , 'TimeStart', 'TimeEnd');

            % fclose(fileID);
            cd(app.MainFolder);

            for k = 1 : length(app.smoothed_by_Morpho_images)
                TimestampStart = char(datetime('now'));
                % Loop through something and update waitbar
                % Update image data (royalblue)
                currentProg = min(round((size(wbar,2)-2)*(k/length(app.Segmented_Images))),size(wbar,2)-2);
                app.SegmentneuronsButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.SegmentneuronsButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.SegmentneuronsButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);

                %     segmentation
                Image = app.smoothed_by_Morpho_images{k};
                % Seg_Image = Segment(Image , segMode);       % Older method (used in previous versions like v4_2 and below)
                % Seg_Image = segment_FiberMet_v01_Function(Image , thickness_pixel);         % Newer version based on FiberMetric method (v5_1 and later)
                % Seg_Image = segment_FiberMet_v02_Function(Image , thickness_pixel);         % Newer version based on FiberMetric method (v5_1 and later)
                % Seg_Image = segment_FiberMet_v03_Function(Image , thickness_pixel);         % Newer version based on FiberMetric method (v5_1 and later)
                
                Seg_Image = segment_FiberMet_v05_Function(Image , thickness_pixel);         % Newer version based on FiberMetric method (v5_1 and later)
                app.Segmented_Images{k} = Seg_Image;
                
                % visualize segmentation
                raw_image = app.smoothed_by_Morpho_images{k};
                imshow(raw_image, [] , 'Parent', app.Image_Input);  % showing only the last segmented image
                imshow(Seg_Image, 'Parent', app.Image_Segmented);  % showing only the last segmented image

                
                TimestampEnd = char(datetime('now'));

                cd Segmented

                fileID = fopen('SegmentTime.txt' , 'a');
                fprintf(fileID , '%20s  %20s  %20s \r\n', app.TifFiles(k).name , TimestampStart, TimestampEnd);
                fclose(fileID);
                   
               
                % save segmented as tif
                filename=strcat('Seg_',app.TifFiles(k).name);
                imwrite( app.Segmented_Images{k}, filename);
                cd(app.MainFolder);


            end

            % remove waitbar
            app.SegmentneuronsButton.Icon = '';
            % Change button name
            app.SegmentneuronsButton.Text = 'Segment neurons';


            % h = msgbox('Image(s) is(are) segmented. Only the last segmented image is shown in the GUI! You can start the Analysis phase!','Help' , 'help');
            disp('**********************************');
            disp('Image(s) has(have) been segmented!');
            disp('**********************************');

            msgbox('Images have been segmented! It is advisable to check the segmented images for artifacts or improper soma placement before proceeding to the classification.');

        end

        % Button pushed function: FindsomaneuritesandbranchesButton
        function FindsomaneuritesandbranchesButtonPushed(app, event)
            % Change button name
            app.ClassificationButton.Text = 'Classifying neurons...';
            % Put text on top of icon
            app.ClassificationButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.ClassificationButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.ClassificationButton.Icon = wbar;


            app.MIN_LEN = round(app.MIN_LEN_micron / app.pix_size);


            %%  Initiating the txt file for results
            % MainFolder = pwd;
            mkdir Results
            cd Results

            fileID = fopen('Results.txt' , 'w');
            fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , 'NeuronIndex' , 'TimeStart', 'TimeEnd',  'SkeletonLength', 'SomaSize' , 'AxonLength' );
            fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , 'BranchPointsNum' , 'PrimBranchNum' , 'PrimBranchLen', 'allBranchPoints', 'allEndPoints' );
            fprintf(fileID , '%20s  %20s  %20s  %20s' , 'SecBranchNum' , 'SecBranchLen', 'TertBranchNum' , 'TertBranchLen' );
            fprintf(fileID , '%20s  %20s  %20s  %20s' , 'DendriteNum' , 'DendriteLen', 'DendBranchNum' , 'DendBranchLen' );
            fprintf(fileID , '%20s  %20s  %20s  %20s %20s %20s %20s \r\n' , 'RatioAxon2Skel' , 'RatioPrim2Skel', 'RatioSec2Skel' , 'RatioTer2Skel' , 'RatioDen2Skel' , 'RatioDenBr2Skel', 'TotalBranchLength');

            mkdir Figures
            cd(app.MainFolder);

            %%

            s = 50; S = 50;   % for 0017.tif , s = 50, S = 50
            tol = 10; extentThr = 0.25;     % for 0017.tif , tol = 10; extentThr = 0.4


            for k = 1 : length(app.Segmented_Images)     % number of loaded segmented images
                % Loop through something and update waitbar
                % Update image data (royalblue)
                currentProgTotal = min(round((size(wbar,2)-2)*(k/length(app.Segmented_Images))),size(wbar,2)-2);
                app.ClassificationButton.Icon(2:end-1, 2:currentProgTotal+1, 1) = 0.25391;
                app.ClassificationButton.Icon(2:end-1, 2:currentProgTotal+1, 2) = 0.41016;
                app.ClassificationButton.Icon(2:end-1, 2:currentProgTotal+1, 3) = 0.87891;


                % Change button name
                app.FindsomaneuritesandbranchesButton.Text = 'Finding soma...';
                % Put text on top of icon
                app.FindsomaneuritesandbranchesButton.IconAlignment = 'bottom';
                % Create waitbar with same color as button
                wbar = permute(repmat(app.FindsomaneuritesandbranchesButton.BackgroundColor,15,1,200),[1,3,2]);
                % Black frame around waitbar
                wbar([1,end],:,:) = 0;
                wbar(:,[1,end],:) = 0;
                % Load the empty waitbar to the button
                app.FindsomaneuritesandbranchesButton.Icon = wbar;

                % Change button name
                app.FindsomaneuritesandbranchesButton.Text = 'Finding soma...';
                % Change status
                currentProg = min(round((size(wbar,2)-2)*(0/4)),size(wbar,2)-2);
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;


                BW = im2bw(app.Segmented_Images{k});
                
                if size(app.smoothed_by_Morpho_images) > 0
                    raw = app.smoothed_by_Morpho_images{k};
                else 
                    raw = app.Segmented_Images{k};
                end
                
                TimestampStart = char(datetime('now'));

                % Erase all open figuers in axes1-4
                BlankBG = imread('BlankBG.png');
                imshow(raw, 'Parent', app.Image_Input, "DisplayRange", [0 150]);
                imshow(BW, 'Parent', app.Image_Segmented);
                imshow(BlankBG, 'Parent', app.Image_soma);
                imshow(BlankBG, 'Parent', app.Image_axon);
                imshow(BlankBG, 'Parent', app.Image_final_classified);

                %% Finding Cell Body (Soma)
                % W = waitbar(0, 'Soma is being extracted . . . ' , 'Name' , 'Progress...' , 'WindowStyle', 'modal');
                % W = waitbar(0, 'Soma is being extracted ...' , 'Name' , ['Progress for Image # ',num2str(k)]);

                %     clc
                disp('*********************************************************');
                disp(['Image Index : ' , num2str(k)]);
                disp('Soma is being extracted . . .');
                disp(' ');

                I = 255*uint8(BW);

                [cBody, sklI, center] = FindCellBody(I, tol, extentThr , s , S, round(1/app.pix_size));

                disp(' ');
                disp('Soma is found! Next step (i.e. Axon Extraction) will start automatically!');
                disp(' ');


                sklI_noSoma = sklI .* (~ bwmorph(cBody,'thin',1));

                endPoints = find(bwmorph(sklI, 'endpoints'));


                somaImage = imoverlay(BW, cBody, [0 0 1] );
                imshow(somaImage, 'Parent',app.Image_soma);

                % h1 = msgbox('Cell Body is Found! Next step (i.e. Axon Extraction) will start automatically!', 'help');
                % pause(1);
                % close(h1);

                %% Extract Axon
                %W = waitbar(0.2 , W , 'Axon is being extracted . . . ' , 'Name' , ['Progress for Image # ',num2str(k)]);
                disp(' ');
                disp('Trying to find the Axon, as longest branch coming out of the Soma . . .');
                disp(' ');

                % Change button name
                app.FindsomaneuritesandbranchesButton.Text = 'Finding axon...';
                % Change status
                currentProg = min(round((size(wbar,2)-2)*(1/4)),size(wbar,2)-2);
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);

                [Neurites, newSkel, axon, endPoints] = FindSomaNeurites(sklI, cBody, endPoints, app.MIN_LEN);

                % axon = FindAxon(sklI, center, endPoints,infMode);
                % msgbox('Axon is found! Next step (i.e. Dendritic Branches Extraction) will start automatically!','help');

                disp(' ');
                disp('Axon and Dendrites are found! Next step (i.e. Axon Branch Extraction) will start automatically!');
                disp(' ');

                image = BW;
                % Axon
                image = imoverlay(image, imdilate(axon{1}, ones(4)), [1 1 0] );

                % Neurites
                for j = 1:size(Neurites)
                    image = imoverlay(image, imdilate(Neurites{j}, ones(4)), [1 0 0] );
                end


                imshow(image, 'Parent',app.Image_axon);


                %% Extract Branches of the axon
                %W = waitbar(0.5 , W , 'Branches of Axon are being extracted . . . ', 'Name' , ['Progress for Image # ',num2str(k)]);
                % Change button name
                app.FindsomaneuritesandbranchesButton.Text = 'Finding axon branches...';
                % Change status
                currentProg = min(round((size(wbar,2)-2)*(2/4)),size(wbar,2)-2);
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);

                AxonBranches = cell(10,1);
                AxonBranches{1} = axon;
                j = 1;
                while ~isempty(AxonBranches{j}) || j > 10
                    disp(['Analysing branch order ', num2str(j)]);
                    j = j+1;
                    [AxonBranches{j}, newSkel, endPoints] = findNextOrderBranch(newSkel, AxonBranches{j-1}, endPoints, app.MIN_LEN);
                end
                %  AxonBranches = AxonBranches(~cellfun('isempty',AxonBranches));

                disp('axon branches detected');

                %% %% find branches on dendrites
                %W = waitbar(0.75 , W , 'Branches of Dendrites are being extracted . . . ', 'Name' , ['Progress for Image # ',num2str(k)]);

                % Change button name
                app.FindsomaneuritesandbranchesButton.Text = 'Finding dendrite branches...';
                % Change status
                currentProg = min(round((size(wbar,2)-2)*(3/4)),size(wbar,2)-2);
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);

                NeuriteBranches = cell(10,1);
                NeuriteBranches{1} = Neurites;
                j = 1;
                while ~isempty(NeuriteBranches{j}) || j > 10
                    disp(['Analysing branch order ', num2str(j)]);
                    j = j+1;
                    [NeuriteBranches{j}, newSkel, endPoints] = findNextOrderBranch(newSkel, NeuriteBranches{j-1}, endPoints, app.MIN_LEN);
                end

                disp('dendrite branches detected');

                % NeuriteBranches = NeuriteBranches(~cellfun('isempty',NeuriteBranches));


                % Branches
                for l = 2:size(AxonBranches)
                    %total = size(AxonBranches);
                    Branches = AxonBranches{l};
                    for j = 1:size(Branches)
                        image = imoverlay(image, imdilate(Branches{j}, ones(4)), [0 1-mod(l-1,4)/3 1-mod(l-1,3)/4] );
                    end
                end

                for l = 2:size(NeuriteBranches)
                    %total = size(NeuriteBranches);
                    Branches = NeuriteBranches{l};
                    for j = 1:size(Branches)
                        image = imoverlay(image, imdilate(Branches{j}, ones(4)), [mod(l-1,4)/3 0 1-mod(l-1,3)/4] );
                    end
                end

                imshow(image, 'Parent',app.Image_final_classified);


                % close(W);
                % W = msgbox('Operation completed! Quantitative results are generated and can be accessed by opening .csv files!' , ['Completed for Image # ',num2str(k)] , 'custom' , imread('checkbox.png'));


                %% Displaying Quantitative Results in the "Command Window"

                disp('------------------------------------------------------- ');
                disp('------------------------------------------------------- ');
                disp(['Quantitative results are generated for Image # ',num2str(k)]);
                disp('------------------------------------------------------- ');
                disp('------------------------------------------------------- ');

                disp(' ');
                cd Results


                %% calculating values

                total_neurite_length = app.pix_size * nnz(sklI_noSoma);
                soma_size = app.pix_size * app.pix_size * nnz(cBody);

                axon_length = app.pix_size * nnz(axon{1});
                prim_branch_points = size(AxonBranches{2});
                prim_branch_length = app.pix_size * nnz(cell2mat(AxonBranches{2}));
                sec_branch_points = size(AxonBranches{3});
                sec_branch_length = app.pix_size * nnz(cell2mat(AxonBranches{3}));
                tert_branch_points = size(AxonBranches{4});
                tert_branch_length = app.pix_size * nnz(cell2mat(AxonBranches{4}));

                axon_branch_points = 0;
                axon_branch_length = 0;
                for l = 2:size(AxonBranches)
                    num = size(AxonBranches{l});
                    axon_branch_points = axon_branch_points + num(1);
                    len = nnz(cell2mat(AxonBranches{l}));
                    axon_branch_length = axon_branch_length + len;
                end
                axon_branch_length = axon_branch_length * app.pix_size;

                dendrite_num = size(Neurites);
                dendrite_length = app.pix_size * nnz(cell2mat(Neurites));

                dendrite_branch_points = 0;
                dendrite_branch_length = 0;
                for l = 2:size(NeuriteBranches)
                    num = size(NeuriteBranches{l});
                    dendrite_branch_points = dendrite_branch_points + num(1);
                    len = nnz(cell2mat(NeuriteBranches{l}));
                    dendrite_branch_length = dendrite_branch_length + len;
                end
                dendrite_branch_length = dendrite_branch_length * app.pix_size;

                %% Saving as .txt  &   .csv   files
                TimestampEnd = char(datetime('now'));

                fileID = fopen('Results.txt' , 'a');

                % fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , 'NeuronIndex' , 'TimeStart', 'TimeEnd',  'SkeletonLength', 'SomaSize' , 'AxonLength' );
                % fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , 'BranchPointsNum' , 'PrimBranchNum' , 'PrimBranchLen', 'allBranchPoints', 'allEndPoints' );
                % fprintf(fileID , '%20s  %20s  %20s  %20s' , 'SecBranchNum' , 'SecBranchLen', 'TertBranchNum' , 'TertBranchLen' );
                % fprintf(fileID , '%20s  %20s  %20s  %20s' , 'DendriteNum' , 'DendriteLen', 'DendBranchNum' , 'DendBranchLen' );
                % fprintf(fileID , '%20s  %20s  %20s  %20s %20s %20s \r\n' , 'RatioAxon2Skel' , 'RatioPrim2Skel', 'RatioSec2Skel' , 'RatioTer2Skel' , 'RatioDen2Skel' , 'RatioDenBr2Skel');

                fprintf(fileID , '%20s  %20s  %20s  %20d  %20d  %20d' , app.TifFiles(k).name , TimestampStart, TimestampEnd, total_neurite_length , soma_size , axon_length);
                fprintf(fileID , '%20d  %20d  %20d  %20d  %20d' , axon_branch_points , prim_branch_points(1) , prim_branch_length, 0,  nnz(endPoints));
                fprintf(fileID , '%20d  %20d  %20d  %20d' , sec_branch_points(1) , sec_branch_length , tert_branch_points(1) , tert_branch_length);
                fprintf(fileID , '%20d  %20d  %20d  %20d' , dendrite_num(1) , dendrite_length , dendrite_branch_points , dendrite_branch_length);
                fprintf(fileID , '%20.1f  %20.1f  %20.1f  %20.1f %20.1f %20.1f %20d \r\n' , 0 , 0 , 0 , 0 , 0 , 0, axon_branch_length );

                fclose(fileID);

                disp('Quantitative measurements can be found within a TXT file (Results.txt) located in the folder "Results"!');
                disp(' ');
                % close(W);

                %% Save all figures
                disp(' ');
                disp('All figures are saved in the "Results\Figures" folder!');
                cd Figures

                filename=strcat('Class_',app.TifFiles(k).name,'.tif');
                imwrite(image, filename);

                cd(app.MainFolder);
                pause(0.5);

            end

            % remove waitbar
            app.FindsomaneuritesandbranchesButton.Icon = '';
            app.ClassificationButton.Icon = '';
            % Change button name
            app.FindsomaneuritesandbranchesButton.Text = 'Find soma, neurites and branches';
            app.ClassificationButton.Text = 'Classification';

        end

        % Value changed function: minimalbranchlengthmEditField
        function minimalbranchlengthmEditFieldValueChanged(app, event)
            app.MIN_LEN_micron = app.minimalbranchlengthmEditField.Value;

        end

        % Button pushed function: LoadsegmentedimagesButton
        function LoadsegmentedimagesButtonPushed(app, event)
            
            % app.MainFolder = pwd;
            [~, pNameSeg] = uigetfile('*.tif');
            CurrentFolder = pNameSeg;
            cd(CurrentFolder);
            app.TifFiles = dir('*.tif');
            numfiles = length(app.TifFiles);
            app.Segmented_Images = cell(1, numfiles);
            for k = 1:numfiles
                app.Segmented_Images{k} = imread(app.TifFiles(k).name);
            end
            % save('TifFiles.mat','TifFiles');

            disp('**********************************');
            disp('Image(s) has(have) been uploaded!');
            disp('**********************************');

            % Image = mydata{k};

            cd(app.MainFolder);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {732, 732};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {606, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGrid = uigridlayout(app.LeftPanel);
            app.Image_Input = uiaxes(app.LeftGrid);
            title(app.Image_Input, 'Input image')
            app.Image_Input.XTick = [];
            app.Image_Input.YTick = [];
            app.Image_Input.Visible = 'off';
            app.Image_Input.Tag = 'Input_image';
            app.Image_Segmented = uiaxes(app.LeftGrid);
            title(app.Image_Segmented, 'Segmented image')
            app.Image_Segmented.XTick = [];
            app.Image_Segmented.YTick = [];
            app.Image_Segmented.Visible = 'off';
            app.Image_Segmented.Tag = 'Input_image';
            app.SegmentationLabel = uilabel(app.LeftGrid);
            app.SegmentationLabel.Text = 'Segmentation';
            app.PreprocessingGrid = uigridlayout(app.LeftGrid);
            app.NoiseremovalusingstructuralelementButton = uibutton(app.PreprocessingGrid, 'push');
            app.NoiseremovalusingstructuralelementButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseremovalusingstructuralelementButtonPushed, true);
            app.NoiseremovalusingstructuralelementButton.Text = 'Noise removal using structural element';
            app.SEsizeEditFieldLabel = uilabel(app.PreprocessingGrid);
            app.SEsizeEditFieldLabel.Text = 'SE size';
            app.SEsizeEditField = uieditfield(app.PreprocessingGrid, 'numeric');
            app.SEsizeEditField.ValueChangedFcn = createCallbackFcn(app, @SEsizeEditFieldValueChanged, true);
            app.SEsizeEditField.Value = 2;
            app.DropDown = uidropdown(app.PreprocessingGrid);
            app.DropDown.Items = {'Circle', 'Disk'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Circle';
            app.SegmentationGrid = uigridlayout(app.LeftGrid);
            app.SegmentneuronsButton = uibutton(app.SegmentationGrid, 'push');
            app.SegmentneuronsButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentneuronsButtonPushed, true);
            app.SegmentneuronsButton.Text = 'Segment neurons';
            app.GapbridgingCheckBox = uicheckbox(app.SegmentationGrid);
            app.GapbridgingCheckBox.Text = 'Gap bridging';
            app.LoadrawimagesButton = uibutton(app.LeftGrid, 'push');
            app.LoadrawimagesButton.ButtonPushedFcn = createCallbackFcn(app, @load_raw_images, true);
            app.LoadrawimagesButton.Text = 'Load raw images';
            app.PreprocessingLabel = uilabel(app.LeftGrid);
            app.PreprocessingLabel.Text = 'Preprocessing';
            app.NeuronsegmenterLabel = uilabel(app.LeftGrid);
            app.NeuronsegmenterLabel.Text = 'Neuron segmenter';
            app.GridLayout2 = uigridlayout(app.LeftGrid);
            app.maximalgapsizemEditFieldLabel = uilabel(app.GridLayout2);
            app.maximalgapsizemEditFieldLabel.Text = 'maximal gap size [µm]';
            app.maximalgapsizemEditField = uieditfield(app.GridLayout2, 'text');
            app.maximalgapsizemEditField.Value = '6';
            app.NeuritethicknessmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NeuritethicknessmEditField.Limits = [0 Inf];
            app.NeuritethicknessmEditField.ValueChangedFcn = createCallbackFcn(app, @NeuritethicknessmEditFieldValueChanged, true);
            app.NeuritethicknessmEditField.Value = 1;
            app.NeuritethicknessmEditFieldLabel = uilabel(app.GridLayout2);
            app.NeuritethicknessmEditFieldLabel.Text = 'Neurite thickness [µm]';
            app.RightPanel = uipanel(app.GridLayout);
            app.ClassificationGrid = uigridlayout(app.RightPanel);
            app.Image_final_classified = uiaxes(app.ClassificationGrid);
            title(app.Image_final_classified, 'Final classification')
            app.Image_final_classified.XTick = [];
            app.Image_final_classified.YTick = [];
            app.Image_final_classified.Visible = 'off';
            app.Image_final_classified.Tag = 'Input_image';
            app.SomaAxonGrid = uigridlayout(app.ClassificationGrid);
            app.Image_axon = uiaxes(app.SomaAxonGrid);
            title(app.Image_axon, 'Axon & Dendrites')
            app.Image_axon.XTick = [];
            app.Image_axon.YTick = [];
            app.Image_axon.Visible = 'off';
            app.Image_axon.Tag = 'Input_image';
            app.Image_soma = uiaxes(app.SomaAxonGrid);
            title(app.Image_soma, 'Soma')
            app.Image_soma.XTick = [];
            app.Image_soma.YTick = [];
            app.Image_soma.Visible = 'off';
            app.Image_soma.Tag = 'Input_image';
            app.FindBranchesGrid = uigridlayout(app.ClassificationGrid);
            app.minimalbranchlengthmEditFieldLabel = uilabel(app.FindBranchesGrid);
            app.minimalbranchlengthmEditFieldLabel.Text = 'minimal branch length [µm]';
            app.minimalbranchlengthmEditField = uieditfield(app.FindBranchesGrid, 'numeric');
            app.minimalbranchlengthmEditField.Limits = [0 Inf];
            app.minimalbranchlengthmEditField.ValueChangedFcn = createCallbackFcn(app, @minimalbranchlengthmEditFieldValueChanged, true);
            app.minimalbranchlengthmEditField.Value = 10;
            app.FindsomaneuritesandbranchesButton = uibutton(app.FindBranchesGrid, 'push');
            app.FindsomaneuritesandbranchesButton.ButtonPushedFcn = createCallbackFcn(app, @FindsomaneuritesandbranchesButtonPushed, true);
            app.FindsomaneuritesandbranchesButton.Text = 'Find soma, neurites and branches';
            app.LoadSegmentedGrid = uigridlayout(app.ClassificationGrid);
            app.pixelsizepxmEditField = uieditfield(app.LoadSegmentedGrid, 'numeric');
            app.pixelsizepxmEditField.Limits = [0 Inf];
            app.pixelsizepxmEditField.Value = 0.2261;
            app.pixelsizepxmEditFieldLabel = uilabel(app.LoadSegmentedGrid);
            app.pixelsizepxmEditFieldLabel.Text = 'pixel size [px/µm]';
            app.LoadsegmentedimagesButton = uibutton(app.LoadSegmentedGrid, 'push');
            app.LoadsegmentedimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsegmentedimagesButtonPushed, true);
            app.LoadsegmentedimagesButton.Text = 'Load segmented images';
            app.NeuronclassifierLabel = uilabel(app.ClassificationGrid);
            app.NeuronclassifierLabel.Text = 'Neuron classifier';
            app.ClassificationButton = uibutton(app.ClassificationGrid, 'push');
            app.ClassificationButton.Text = 'Classification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Neuron_Classifier2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end